classdef back_end_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Back_end_surface       matlab.ui.Figure                   % Back_en...
        Panel                  matlab.ui.container.Panel          % Data Se...
        DataFile               matlab.ui.control.Button           % Choose ...
        PlotSelectedData       matlab.ui.control.Button           % Plot Up...
        DataFileName           matlab.ui.control.EditField        % Data Fi...
        MoreDataFiles          matlab.ui.control.Button           % Upload ...
        VariableName           matlab.ui.control.EditField        % (temp, ...
        variableval            matlab.ui.control.NumericEditField % [-Inf Inf]
        Label15                matlab.ui.control.Label            % Variabl...
        Label16                matlab.ui.control.Label            % Variabl...
        TabGroup               matlab.ui.container.TabGroup       % Data FI...
        Tab                    matlab.ui.container.Tab            % Data FIles
        DataFilesPlot          matlab.ui.control.UIAxes           % Data Files
        Tab3                   matlab.ui.container.Tab            % Normali...
        NormalizedData         matlab.ui.control.UIAxes           % Normali...
        Tab4                   matlab.ui.container.Tab            % Fitting...
        fitparaplot            matlab.ui.control.UIAxes           % Fitting...
        LabelDropDown          matlab.ui.control.Label            % Parameters
        PlotParameters         matlab.ui.control.DropDown         % Amplitu...
        Panel2                 matlab.ui.container.Panel          % Functio...
        LabelListBox           matlab.ui.control.Label           
        FunctionLibrary        matlab.ui.control.ListBox          % Single ...
        Panel3                 matlab.ui.container.Panel          % Initial...
        LabelNumericEditField  matlab.ui.control.Label            % Amplitude
        Amplitude              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                 matlab.ui.control.Label            % Decay time
        DecayTime              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label5                 matlab.ui.control.Label            % Constan...
        ConstantTimeOffset     matlab.ui.control.NumericEditField % [-Inf Inf]
        FitToEquation          matlab.ui.control.Button           % Fit to ...
        Label7                 matlab.ui.control.Label            % Fitting...
        filetobefitted         matlab.ui.control.EditField        % Data to...
        Label4                 matlab.ui.control.Label            % X-axis
        Xaxis                  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label6                 matlab.ui.control.Label            % to
        xaxismax               matlab.ui.control.NumericEditField % [-Inf Inf]
        ButtonGroup            matlab.ui.container.ButtonGroup    % Fitting...
        initialize             matlab.ui.control.RadioButton      % Initialize
        prevresult             matlab.ui.control.RadioButton      % Use pre...
        fitpararesult          matlab.ui.container.Panel          %  Fit Re...
        Label8                 matlab.ui.control.Label            % Amplitude
        fita                   matlab.ui.control.NumericEditField % [-Inf Inf]
        Label9                 matlab.ui.control.Label            % Decay time
        fitT                   matlab.ui.control.NumericEditField % [-Inf Inf]
        Label10                matlab.ui.control.Label            % Constan...
        fitC                   matlab.ui.control.NumericEditField % [-Inf Inf]
        continuetonext         matlab.ui.control.Button           % Continu...
        Label11                matlab.ui.control.Label            % Fitting...
        filefitted             matlab.ui.control.EditField        % Data fi...
        Label12                matlab.ui.control.Label            % X-axis
        xminf                  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label13                matlab.ui.control.Label            % to
        xmaxf                  matlab.ui.control.NumericEditField % [-Inf Inf]
        plotfittingresult      matlab.ui.control.Button           % Plot fi...
        Panel4                 matlab.ui.container.Panel          % Normali...
        LabelNumericEditField2 matlab.ui.control.Label            % Minimum
        ScaleMin               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label14                matlab.ui.control.Label            % Maximum
        ScaleMax               matlab.ui.control.NumericEditField % [-Inf Inf]
        PlotNormData           matlab.ui.control.Button           % Plot No...
    end


    properties (Access = private)
        % create private variables that can be shared among callback functions
        
        FittingEq % Equation to be  fitted to
        amp % fitting parameters result (used to fit the next data set)
        delaytime
        constant
        xmin % fitting range
        xmax
        variablename % example: temperature, pump power
        fitnumber % to keep track of the data file to be fitted 
        
    end
    
    properties (Access = public)
        datainfo % cell array with data file info (check excel sheet to see layout)
        rawdatax % cell array containing data x {datafile1, datafile2....}
                                              %  datafile = column matrix of data x
        rawdatay % cell array containing data y
        nfiles % number of files loaded
    end
    
    methods (Access = public)
        
        function [filename, datax, datay] = loadFiles(app) 
            % prompt for data selection
            [filename,PathName] = uigetfile('*.csv');
            FullPath = fullfile(PathName, filename);
            % read file as a matrix 
            M = csvread(FullPath);
            datax = M(:,1); % select each column
            datay = M(:,2);
        end
        
    end
    
    methods (Access = private)
        
        
        function [normx, normy] = normalize(app, datax, datay, max, min)
            % given the min and max of the data and the scale for nomalization
            % this function outputs the normalized data.
            % datax and datay is the data points to be normalized.
            % max and min is the max and min of the data files uploaded.
            
            a = app.ScaleMin.Value;
            b = app.ScaleMax.Value;
            
            normy = a + ((datay - min)*(b - a)/(max - min));
            normx = datax;
        end


        function [Max, Min] = findmaxmin(app, nfiles)
            % put all the y data into an matrix
            A = [];
            for i = 1:nfiles
                A = [A; app.rawdatay{1, i}];
            end
           
            [Max, ~] = max(A(:)); % find the max of the entire matrix
            [Min, ~] = min(A(:));
        end


        function [fitresult, gof] = fitsingleexp(app, fitT1, fitdRR1, name)
            % Currently work for SINGLE EXPONENTIAL EQUATION
            % This code is generated by CURVE FITTING TOOLS
            
            %CREATEFIT1(FITT1,FITDRR1)
            %  Create a fit.
            %
            %  Data for 'untitled fit 1' fit:
            %      X Input : fitT1
            %      Y Output: fitdRR1
            %  Output:
            %      fitresult : a fit object representing the fit.
            %      gof : structure with goodness-of fit info.
            %
            %  See also FIT, CFIT, SFIT.
            
            %  Auto-generated by MATLAB on 31-Aug-2016 18:03:44
            
            
            [xData, yData] = prepareCurveData( fitT1, fitdRR1);
            
            % Set up fittype and options.
            ft = fittype( 'a*exp(-x/b)+c', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.StartPoint = [app.Amplitude.Value app.DecayTime.Value app.ConstantTimeOffset.Value];
            
            % Fit model to data.
            [fitresult, gof] = fit( xData, yData, ft, opts );
            
            % Plot fit with data. Creates a new window/figure with the graph
            figure( 'Name', name);
            subplot(2,1,1) 
            title('fit result and raw data');
            h = plot(fitresult, xData, yData );
            legend( h, name, 'Fit Result', 'Location', 'East' );
            % Label axes
            xlabel Time
            ylabel dR/R
            grid on
            
            subplot(2,1,2) 
            coeff = coeffvalues(fitresult); % to get the coefficients
            ampl = coeff(1);
            delayTime = coeff(2);
            constantoff = coeff(3);
            d = plot(xData, yData-((ampl)*exp(-(xData)/(delayTime))+constantoff));
            % Label axes
            xlabel Time
            ylabel Raw-Fit
            grid on
            
        end


        function [ampl, delayTime, constantoff] = fit(app, datax, datay, filename)
            if strcmp(app.FittingEq, 'Aexp(-t/T) + C') == 1 % if single exp equ is selected
                % fit data file 1
                x = find(datax == app.Xaxis.Value); % find index of time range
                y = find(datax == app.xaxismax.Value);
                app.xmin = app.Xaxis.Value;
                app.xmax = app.xaxismax.Value;
                FitdR = datay(x:y); % get the data points within this range
                FitTime = datax(x:y);
                [fitresult, ~] = fitsingleexp(app, FitTime, FitdR, filename); % fit the equation to range
                coeff = coeffvalues(fitresult); % to get the coefficients
                ampl = coeff(1);
                delayTime = coeff(2);
                constantoff = coeff(3);
                app.amp = ampl;
                app.delaytime = delayTime;
                app.constant = constantoff;
                % finishing fitting data file 1
            else
                % fitting procedure for other fitting equations
            end
            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.amp = 0;
            app.delaytime = 0;
            app.constant = 0;
            app.xmin = 0;
            app.xmax = 0;
            app.datainfo = cell(2, 5);
            app.rawdatax = cell(1, 2);
            app.rawdatay = cell(1, 2);
            app.nfiles = 0;
            app.variablename = 0;
            app.fitnumber = 1;
            app.FittingEq = 'Aexp(-t/T) + C';
        end

        % DataFile button pushed function
        function DataFileButtonPushed(app)
            % load files
            [file, time, dRR] = loadFiles(app);
            % display the name of file uploaded
            app.DataFileName.Value = file;
            % update number of files uploaded
            app.nfiles = app.nfiles + 1;
            i = app.nfiles;
            % save information on the cell arrays
            app.datainfo{i, 1} = file; 
            app.rawdatax{1, i} = time;
            app.rawdatay{1, i} = dRR;
            
            if app.nfiles == 1
            % since it's the first file
                app.filetobefitted.Value = app.DataFileName.Value;
            end
            
        end

        % PlotSelectedData button pushed function
        function PlotSelectedDataButtonPushed(app)
            plot(app.DataFilesPlot, app.rawdatax{1, 1}, app.rawdatay{1, 1}); % plot file 1 onto the axis
            if app.nfiles > 1
                for i = 2:app.nfiles
                    hold(app.DataFilesPlot, 'on');
                    plot(app.DataFilesPlot, app.rawdatax{1, i}, app.rawdatay{1, i});
                end
            end
            app.DataFilesPlot.XLabel.String = 'Delay Time'; % set axis labels
            app.DataFilesPlot.YLabel.String = 'dR/R';
            hold(app.DataFilesPlot, 'off');
        end

        % FunctionLibrary value changed function
        function FunctionLibraryValueChanged(app)
            value = app.FunctionLibrary.Value;
            if strcmp(value,'Single Exponential Function') == 1
                app.FittingEq = 'Aexp(-t/T) + C';
            elseif strcmp(value, 'Other functions') == 1
                % TODO : prompt for user input
                app.FittingEq = '0'; % temporary
            end
        end

        % PlotNormData button pushed function
        function PlotNormDataButtonPushed(app)
            
            if app.nfiles == 1 % if there is only one file
                Max = max(app.rawdatay{1,1}); % find max min of data 1
                Min = min(app.rawdatay{1,1});
                [normx1, normy1] = normalize(app, app.rawdatax{1,1}, app.rawdatay{1,1}, Max, Min); % normalized data 1
                plot(app.NormalizedData, normx1, normy1);
            else
                norm = cell(2, 2); % save normalized data onto an cell array
                [Max, Min] = findmaxmin(app, app.nfiles); % find max min of entire data set
                for i = 1:app.nfiles
                    [norm{i, 1}, norm{i, 2}] = normalize(app, app.rawdatax{1,i}, app.rawdatay{1,i}, Max, Min); % normalize data
                end
                for k = 1:app.nfiles
                    plot(app.NormalizedData, norm{k, 1}, norm{k, 2}); % plot
                    hold(app.NormalizedData, 'on');
                end
                hold(app.NormalizedData, 'off');
            end
            
        end

        % FitToEquation button pushed function
        function FitToEquationButtonPushed(app)
            i = app.fitnumber;
            if strcmp(app.FittingEq, 'Aexp(-t/T) + C') == 1
                % fit data file to single exponential equation
                [app.datainfo{i,3}, app.datainfo{i,4}, app.datainfo{i,5}] = fit(app, app.rawdatax{1,i}, app.rawdatay{1,i}, app.datainfo{i, 1});
                % save fit parameters onto data info array
                app.fita.Value = app.datainfo{i,3};
                app.fitT.Value = app.datainfo{i,4};
                app.fitC.Value = app.datainfo{i,5};
                % display the name of the file just fitted
                app.filefitted.Value = app.datainfo{i, 1};
                app.xminf.Value = app.xmin;
                app.xmaxf.Value = app.xmax;
            else
                % fitting procedure for other fitting equations
            end
            
        end

        % continuetonext button pushed function
        function continuetonextButtonPushed(app)
            app.fitnumber = app.fitnumber + 1; % increment fitting number index (this is for keep
                                               % track of the files fitted 
            app.filetobefitted.Value = app.datainfo{app.fitnumber, 1};
        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.prevresult
                % to use prev result as the initial fitting parameters
                app.Amplitude.Value = app.amp;
                app.DecayTime.Value = app.delaytime;
                app.ConstantTimeOffset.Value = app.constant;
                % makes the edit fields uneditable
                set(app.Amplitude, 'Editable', 'off');
                set(app.DecayTime, 'Editable', 'off');
                set(app.ConstantTimeOffset, 'Editable', 'off');
            else
                % to use customizable initial fitting parameters
                % makes edit fields editable
                set(app.Amplitude, 'Editable', 'on');
                set(app.DecayTime, 'Editable', 'on');
                set(app.ConstantTimeOffset, 'Editable', 'on');
            end
        end

        % MoreDataFiles button pushed function
        function MoreDataFilesButtonPushed(app)
            app.DataFileName.Value = 'Upload next data';
            app.variableval.Value = 0;
        end

        % VariableName value changed function
        function VariableNameValueChanged(app)
            value = app.VariableName.Value;
            app.variablename = value;
        end

        % variableval value changed function
        function variablevalValueChanged(app)
            value = app.variableval.Value;
            % save the variable value (i.e. temp) on to the data info array
            app.datainfo{app.nfiles,2} = value;
        end

        % plotfittingresult button pushed function
        function plotfittingresultButtonPushed(app)
            x = [];
            y = [];
            for i = 1:app.nfiles
                x = [x; app.datainfo{i,2}];
            end
            for k = 1:app.nfiles
                if strcmp(app.PlotParameters.Value, 'Amplitude') == 1
                    y = [y; app.datainfo{k,3}];
                elseif strcmp(app.PlotParameters.Value, 'Delay Time') == 1
                    y = [y; app.datainfo{k,4}];
                else
                    y = [y; app.datainfo{k,5}];
                end
            end
            plot(app.fitparaplot, x, y);
            app.fitparaplot.XLabel.String = app.variablename;
            app.fitparaplot.YLabel.String = app.PlotParameters.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Back_end_surface = uifigure;
            app.Back_end_surface.Name = 'Back_end_interface';
            setAutoResize(app, app.Back_end_surface, false)
            app.Panel = uipanel(app.Back_end_surface);
            app.Panel.Title = 'Data Selection';
            app.Panel.Units = 'pixels';
            app.DataFile = uibutton(app.Panel, 'push');
            app.DataFile.ButtonPushedFcn = createCallbackFcn(app, @DataFileButtonPushed);
            app.DataFile.Text = 'Choose Data';
            app.PlotSelectedData = uibutton(app.Panel, 'push');
            app.PlotSelectedData.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedDataButtonPushed);
            app.PlotSelectedData.Text = 'Plot Uploaded Data';
            app.DataFileName = uieditfield(app.Panel, 'text');
            app.DataFileName.Editable = 'off';
            app.DataFileName.Value = 'Data File Name';
            app.MoreDataFiles = uibutton(app.Panel, 'push');
            app.MoreDataFiles.ButtonPushedFcn = createCallbackFcn(app, @MoreDataFilesButtonPushed);
            app.MoreDataFiles.Text = 'Upload Next Data';
            app.VariableName = uieditfield(app.Panel, 'text');
            app.VariableName.ValueChangedFcn = createCallbackFcn(app, @VariableNameValueChanged);
            app.VariableName.Value = '(temp, pump power, etc.)';
            app.variableval = uieditfield(app.Panel, 'numeric');
            app.variableval.ValueChangedFcn = createCallbackFcn(app, @variablevalValueChanged);
            app.Label15 = uilabel(app.Panel);
            app.Label15.Text = 'Variable Values';
            app.Label16 = uilabel(app.Panel);
            app.Label16.Text = 'Variable Name';
            app.TabGroup = uitabgroup(app.Back_end_surface);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Data FIles';
            app.DataFilesPlot = uiaxes(app.Tab);
            title(app.DataFilesPlot, 'Data Files');
            xlabel(app.DataFilesPlot, 'X');
            ylabel(app.DataFilesPlot, 'Y');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Normalized Data';
            app.NormalizedData = uiaxes(app.Tab3);
            title(app.NormalizedData, 'Normalized Data');
            xlabel(app.NormalizedData, 'Time');
            ylabel(app.NormalizedData, 'Normalized dR/R');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Fitting Result';
            app.fitparaplot = uiaxes(app.Tab4);
            title(app.fitparaplot, 'Fitting Result');
            xlabel(app.fitparaplot, 'X');
            ylabel(app.fitparaplot, 'Y');
            app.LabelDropDown = uilabel(app.Tab4);
            app.LabelDropDown.Text = 'Parameters';
            app.PlotParameters = uidropdown(app.Tab4);
            app.PlotParameters.Items = {'Amplitude', 'Delay Time', 'Constant Offset'};
            app.PlotParameters.Value = 'Amplitude';
            app.Panel2 = uipanel(app.Back_end_surface);
            app.Panel2.Title = 'Function Library';
            app.Panel2.Units = 'pixels';
            app.LabelListBox = uilabel(app.Panel2);
            app.LabelListBox.Text = '';
            app.FunctionLibrary = uilistbox(app.Panel2);
            app.FunctionLibrary.Items = {'Single Exponential Function', 'Other functions'};
            app.FunctionLibrary.ValueChangedFcn = createCallbackFcn(app, @FunctionLibraryValueChanged);
            app.FunctionLibrary.Value = 'Single Exponential Function';
            app.Panel3 = uipanel(app.Back_end_surface);
            app.Panel3.Title = 'Initial Fitting Parameters';
            app.Panel3.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel3);
            app.LabelNumericEditField.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.Panel3, 'numeric');
            app.Label3 = uilabel(app.Panel3);
            app.Label3.Text = 'Decay time';
            app.DecayTime = uieditfield(app.Panel3, 'numeric');
            app.Label5 = uilabel(app.Panel3);
            app.Label5.Text = 'Constant Time Offset';
            app.ConstantTimeOffset = uieditfield(app.Panel3, 'numeric');
            app.FitToEquation = uibutton(app.Panel3, 'push');
            app.FitToEquation.ButtonPushedFcn = createCallbackFcn(app, @FitToEquationButtonPushed);
            app.FitToEquation.Text = 'Fit to Equation';
            app.Label7 = uilabel(app.Panel3);
            app.Label7.Text = 'Fitting Range';
            app.filetobefitted = uieditfield(app.Panel3, 'text');
            app.filetobefitted.Editable = 'off';
            app.filetobefitted.Value = 'Data to be fitted';
            app.Label4 = uilabel(app.Panel3);
            app.Label4.Text = 'X-axis';
            app.Xaxis = uieditfield(app.Panel3, 'numeric');
            app.Label6 = uilabel(app.Panel3);
            app.Label6.Text = 'to';
            app.xaxismax = uieditfield(app.Panel3, 'numeric');
            app.ButtonGroup = uibuttongroup(app.Panel3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Fitting Parameters';
            app.ButtonGroup.Units = 'pixels';
            app.initialize = uiradiobutton(app.ButtonGroup);
            app.initialize.Text = 'Initialize';
            app.initialize.Value = true;
            app.prevresult = uiradiobutton(app.ButtonGroup);
            app.prevresult.Text = 'Use previous result';
            app.fitpararesult = uipanel(app.Back_end_surface);
            app.fitpararesult.Title = ' Fit Result';
            app.fitpararesult.Units = 'pixels';
            app.Label8 = uilabel(app.fitpararesult);
            app.Label8.Text = 'Amplitude';
            app.fita = uieditfield(app.fitpararesult, 'numeric');
            app.fita.ValueDisplayFormat = '%.4f';
            app.fita.Editable = 'off';
            app.Label9 = uilabel(app.fitpararesult);
            app.Label9.Text = 'Decay time';
            app.fitT = uieditfield(app.fitpararesult, 'numeric');
            app.fitT.ValueDisplayFormat = '%.4f';
            app.fitT.Editable = 'off';
            app.Label10 = uilabel(app.fitpararesult);
            app.Label10.Text = 'Constant Time Offset';
            app.fitC = uieditfield(app.fitpararesult, 'numeric');
            app.fitC.ValueDisplayFormat = '%.4f';
            app.fitC.Editable = 'off';
            app.continuetonext = uibutton(app.fitpararesult, 'push');
            app.continuetonext.ButtonPushedFcn = createCallbackFcn(app, @continuetonextButtonPushed);
            app.continuetonext.Text = 'Continue to next data';
            app.Label11 = uilabel(app.fitpararesult);
            app.Label11.Text = 'Fitting Range';
            app.filefitted = uieditfield(app.fitpararesult, 'text');
            app.filefitted.Editable = 'off';
            app.filefitted.Value = 'Data fitted';
            app.Label12 = uilabel(app.fitpararesult);
            app.Label12.Text = 'X-axis';
            app.xminf = uieditfield(app.fitpararesult, 'numeric');
            app.xminf.Editable = 'off';
            app.Label13 = uilabel(app.fitpararesult);
            app.Label13.Text = 'to';
            app.xmaxf = uieditfield(app.fitpararesult, 'numeric');
            app.xmaxf.Editable = 'off';
            app.plotfittingresult = uibutton(app.fitpararesult, 'push');
            app.plotfittingresult.ButtonPushedFcn = createCallbackFcn(app, @plotfittingresultButtonPushed);
            app.plotfittingresult.Text = 'Plot fitting parameters';
            app.Panel4 = uipanel(app.Back_end_surface);
            app.Panel4.Title = 'Normalize Data';
            app.Panel4.Units = 'pixels';
            app.LabelNumericEditField2 = uilabel(app.Panel4);
            app.LabelNumericEditField2.Text = 'Minimum';
            app.ScaleMin = uieditfield(app.Panel4, 'numeric');
            app.Label14 = uilabel(app.Panel4);
            app.Label14.Text = 'Maximum';
            app.ScaleMax = uieditfield(app.Panel4, 'numeric');
            app.PlotNormData = uibutton(app.Panel4, 'push');
            app.PlotNormData.ButtonPushedFcn = createCallbackFcn(app, @PlotNormDataButtonPushed);
            app.PlotNormData.Text = 'Plot Normalize Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = back_end_v4()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Back_end_surface)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Back_end_surface)
        end
    end
end